classdef CirclePlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CIrclePlotUIFigure      matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        UITable_2               matlab.ui.control.Table
        SectorsSpinnerLabel     matlab.ui.control.Label
        SectorsSpinner          matlab.ui.control.Spinner
        RingsSpinnerLabel       matlab.ui.control.Label
        RingsSpinner            matlab.ui.control.Spinner
        MakePlotButton          matlab.ui.control.Button
        UITable_3               matlab.ui.control.Table
        ColormapsTextAreaLabel  matlab.ui.control.Label
        ColormapsTextArea       matlab.ui.control.TextArea
    end

    methods (Access = private)
       function updateplot(app)
       end   
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add data to the Table UI Component
            app.UITable.Data = zeros(12,4);
            app.UITable_2.Data = {'fire','fire','fire','fire'};
            app.UITable_3.Data = ones(12);

        end

        % Button pushed function: MakePlotButton
        function MakePlotButtonPushed(app, event)
            makeplot(app.SectorsSpinner.Value,app.RingsSpinner.Value ...
                ,app.UITable.Data,app.UITable_3.Data,app.UITable_2.Data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CIrclePlotUIFigure = uifigure;
            app.CIrclePlotUIFigure.Name = 'CIrclePlot';
            app.UITable = uitable(app.CIrclePlotUIFigure);
            app.UITable.ColumnName = {'Sector 1'; 'Sector 2'; 'Sector 3'; 'Sector 4'};
            app.UITable.RowName = {'Ring 1'; ' Ring 2'; ' Ring 3'; ' Ring 4'; ' Ring 5'; ' Ring 6'; ' Ring 7'; ' Ring 8 '; 'Ring 9'; 'Ring 10'; 'Ring 11'; 'Ring 12'; ''};
            app.UITable.ColumnEditable = [true true true true];
            app.UITable_2 = uitable(app.CIrclePlotUIFigure);
            app.UITable_2.ColumnName = {'Sector 1'; 'Sector 2'; 'Sector 3'; 'Sector 4'};
            app.UITable_2.RowName = {'Colormap'; ''};
            app.UITable_2.ColumnEditable = true;
            app.SectorsSpinnerLabel = uilabel(app.CIrclePlotUIFigure);
            app.SectorsSpinnerLabel.Text = 'Sectors';
            app.SectorsSpinner = uispinner(app.CIrclePlotUIFigure);
            app.SectorsSpinner.Limits = [1 4];
            app.SectorsSpinner.Value = 1;
            app.RingsSpinnerLabel = uilabel(app.CIrclePlotUIFigure);
            app.RingsSpinnerLabel.Text = 'Rings';
            app.RingsSpinner = uispinner(app.CIrclePlotUIFigure);
            app.RingsSpinner.Limits = [1 12];
            app.RingsSpinner.Value = 3;
            app.MakePlotButton = uibutton(app.CIrclePlotUIFigure, 'push');
            app.MakePlotButton.ButtonPushedFcn = createCallbackFcn(app, @MakePlotButtonPushed, true);
            app.MakePlotButton.Text = 'Make Plot';
            app.UITable_3 = uitable(app.CIrclePlotUIFigure);
            app.UITable_3.ColumnName = {'Size'};
            app.UITable_3.RowName = {'Ring 1'; ' Ring 2'; ' Ring 3'; ' Ring 4'; ' Ring 5'; ' Ring 6'; ' Ring 7'; ' Ring 8 '; 'Ring 9'; 'Ring 10'; 'Ring 11'; 'Ring 12'; ''};
            app.UITable_3.ColumnEditable = true;
            app.ColormapsTextAreaLabel = uilabel(app.CIrclePlotUIFigure);
            app.ColormapsTextAreaLabel.Text = 'Colormaps';
            app.ColormapsTextArea = uitextarea(app.CIrclePlotUIFigure);
            app.ColormapsTextArea.Value = {'green'; 'orange'; 'blue'; 'magenta'; 'ygb'; 'gbm'; 'fire'; 'firegb'; ''; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = CirclePlot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CIrclePlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CIrclePlotUIFigure)
        end
    end
end